classdef ReviewOptodeIDs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReviewOptodeIdentitiesUIFigure  matlab.ui.Figure
        DisplayOptionsPanel             matlab.ui.container.Panel
        UnassignedOptodesCheckBox       matlab.ui.control.CheckBox
        TemplateSurfaceCheckBox         matlab.ui.control.CheckBox
        ModelSurfaceCheckBox            matlab.ui.control.CheckBox
        TemplateOptodesCheckBox         matlab.ui.control.CheckBox
        ManuallyAssignedOptodesCheckBox  matlab.ui.control.CheckBox
        OptodeAssignmentsCheckBox       matlab.ui.control.CheckBox
        AssignedOptodeMarkersCheckBox   matlab.ui.control.CheckBox
        TemplateSurfaceLegendAx         matlab.ui.control.UIAxes
        UnassignedOptodesLegendAx       matlab.ui.control.UIAxes
        ModelSurfaceLegendAx            matlab.ui.control.UIAxes
        TemplateOptodesLegendAx         matlab.ui.control.UIAxes
        AssignedOptodeMarkersLegendAx   matlab.ui.control.UIAxes
        ManuallyAssignedOptodesLegendAx  matlab.ui.control.UIAxes
        OptodeAssignmentsLegendAx       matlab.ui.control.UIAxes
        centerbModelbbrLabel            matlab.ui.control.Label
        centerbTemplatebbrRealignedtomodeloptodepositionscenterLabel  matlab.ui.control.Label
        RealignButton                   matlab.ui.control.Button
        RegisterButton                  matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        UIAxes_modl                     matlab.ui.control.UIAxes
        UIAxes_tmpl                     matlab.ui.control.UIAxes
    end

        
    % TODOs:
    % - use handles for markers & ID text
    % - revise plot fncs? need possibility to add optodes from template
    %   using liner model / least squares from surrounding optodes.
    %  - fix updateAssignment
    %  
    properties (Access = public)
        % function handle to return property values on figure CloseRequest
        propReturnFnc = @()disp([])

    end
    
    
    properties (SetAccess = private)
        % Mx3 array containing template optode coordinates
        tmpl_optodePositions (:,3) double % = struct('xyz',[],'ID',[],'normals',[])
        
        % cellstring of template optode IDs
        tmpl_optodeIDs (:,1) cell {iscellstr(tmpl_optodeIDs)} = cell(0,1)
        
        % Mx3x2 array containing model optode coordinates (projections and origins)
        modl_optodePositions (:,3,2) double % = struct('xyz',[],'ID',[],'normals',[])
        
        % cellstring of model optode IDs (can be assigned from the GUI)
        modl_optodeIDs (:,1) cell {iscellstr(modl_optodeIDs)} = cell(0,1)
        
        % struct containing template head surface ('faces', 'vertices' [, 'color'])
        tmpl_headSurface (1,1) struct = struct('faces',[],'vertices',[],'color',[])
        
        % struct containing model head surface ('faces', 'vertices' [, 'color'])
        modl_headSurface (1,1) struct = struct('faces',[],'vertices',[],'color',[])
        
        % manually assigned optode
        modl_optodeIsManAssigned (:,1) logical
    end
    
    
    properties (Constant)
        inputArgsPropertyNames (1,:) cell {OptodeReg.mustBeProperty(inputArgsPropertyNames,'ReviewOptodeIDs')} = ...
            {'tmpl_optodePositions', 'modl_optodePositions', ...
             'tmpl_optodeIDs',       'modl_optodeIDs', ... modl_optodeIDs have to be set AFTER tmpl-IDs and *optodePositions!
             'tmpl_headSurface',     'modl_headSurface'};
    end
    
    
    properties (SetAccess = private)
        optMarkerContextMenu (1,1) matlab.ui.container.ContextMenu %= uicontextmenu()
        lastSelectedTmplOpt (1,1) matlab.graphics.chart.primitive.Line

        hndl_tmplOptodeMarker (:,1) matlab.graphics.chart.primitive.Line
        hndl_tmplOptodeID (:,1) matlab.graphics.primitive.Text
        hndl_modlOptodeMarker (:,2) matlab.graphics.chart.primitive.Line
        hndl_modlOptodeID (:,1) matlab.graphics.primitive.Text
        hndl_optodeAssignLine (:,1) matlab.graphics.chart.primitive.Line
    end
    
    
    properties (Constant)%,Hidden
        props_tmplHeadSurf (1,1) struct = struct(...
                         'FaceAlpha',          .4, ...
                         'PickableParts',      'none', ...
                         'HitTest',            'off', ...
                         'Tag',                'tmplHeadSurf');
        props_modlHeadSurf (1,1) struct = struct(...
                         'FaceAlpha',          1, ...
                         'PickableParts',      'none', ...
                         'HitTest',            'off', ...
                         'Tag',                'modlHeadSurf');
        props_assignLine (1,1) struct = struct(...
                         'LineWidth',           2, ...
                         'Color',               'k', ...
                         'LineStyle',           '-', ...
                         'Tag',                 'optAssignLine');
        props_tmplOptode (1,1) struct = struct(...
                         'LineStyle',           '-', ...
                         'LineWidth',           1, ...
                         'Marker',              'o', ...
                         'MarkerSize',          20, ...
                         'MarkerEdgeColor',     'k',...
                         'Tag',                 'tmpl_optPosMarker');
        props_modlOptode (1,1) struct = struct(...
                         'LineStyle',           '-', ...
                         'LineWidth',           3, ...
                         'Marker',              'o', ...
                         'MarkerSize',          20, ...
                         'MarkerEdgeColor',     'none',...
                         'Tag',                 'modl_optPosMarker');
        props_tmplOptID (1,1) struct = struct(...
                         'HorizontalAlignment', "center", ...
                         'VerticalAlignment',   "middle", ...
                         'PickableParts',       'none', ...
                         'HitTest',             'off', ...
                         'Tag',                 'tmpl_optPosID');
        props_modlOptID (1,1) struct = struct(...
                         'HorizontalAlignment', "center", ...
                         'VerticalAlignment',   "middle", ...
                         'PickableParts',       'none', ...
                         'HitTest',             'off', ...
                         'Tag',                 'modl_optPosID');
        props_isSrcMarker (1,1) struct = struct(...
                         'MarkerFaceColor',     [255, 146, 112]./255);
        props_isDetMarker (1,1) struct = struct(...
                         'MarkerFaceColor',     [121, 191, 252]./255);
        props_isOtherMarker (1,1) struct = struct(...
                         'MarkerFaceColor',     [245, 245, 245]./255);
        props_isUnassigned (1,1) struct = struct(...
                         'MarkerFaceColor',     [242, 236, 0]./255);
        props_manAssigned (1,1) struct = struct(...
                         'MarkerEdgeColor',     [242, 236, 0]./255);
    end


    %% ###################### SET METHODS ############################
    
    
    methods 


        %..................................................................
            
        function set.tmpl_optodePositions(app,val)
            app.tmpl_optodePositions = val;
%             app.plotOptPositions('tmpl');
        end
        
        %..................................................................
            
        function set.tmpl_optodeIDs(app,val)
            msg ='';
            if any(cellfun(@isempty,val))
                msg = 'Template optode ID must not be empty!';
            end
            if numel(val)~=numel(unique(val))
                msg = 'Template optode IDs must be unique!';
            end
            if ~isempty(msg)
                error(msg);
            end
            
            app.tmpl_optodeIDs = val;
        end
        
        %..................................................................
        
        function set.tmpl_headSurface(app,val)
            app.tmpl_headSurface = val;
            app.plotSurf('tmpl');
        end
        
        %..................................................................
        
%         function set.modl_optodePositions(app,val)
%             app.modl_optodePositions = val;
%             app.plotOptPositions('modl');
%         end
        %..................................................................
        
        function set.modl_headSurface(app,val)
            app.modl_headSurface = val;
            app.plotSurf('modl');
        end
        
        %..................................................................
        
        function set.modl_optodeIDs(app,val)
            nNew = numel(val); nOld = numel(app.modl_optodeIDs);
            
            if nNew ~= size(app.modl_optodePositions,1)
                error('Number of optode IDs does not match number of positions. Set positions before IDs!')
            end
            
            if nNew == nOld
                k = ~strcmp(val(:),app.modl_optodeIDs(:));
            else  % nOld == 0 || nNew~=nOld
                k = 1:nNew;
%             elseif nNew > nOld
%                 k = ~strcmp(val(1:nOld),app.modl_optodeIDs(:));
            end
            app.modl_optodeIDs = val;
            app.updateAssignment(k);
        end


    %% ###################### GET METHODS ############################


%         function val = get.optodeIDs(app)
%             if isfield(app.tmpl_optodePositions,'ID')
%                 val = app.tmpl_optodePositions.ID;
%             else
%                 val = [];
%             end
%         end
    end
    
    
    %% #################### METHODS public ################################
    
    
    methods (Access = public)
        
        
        function updateOptContextMenu(app)
            val = app.tmpl_optodeIDs;
            
            delete(app.optMarkerContextMenu.Children);
            uimenu(app.optMarkerContextMenu,'Text',     'clear ID', ...
                    'MenuSelectedFcn',                  @(src,evt)app.cb_assignOptID(src,evt), ...
                    'UserData',                         -1);
            
            uimenu(app.optMarkerContextMenu,'Text',     'delete Optode', ...
                    'MenuSelectedFcn',                  @(src,evt)app.cb_assignOptID(src,evt), ...
                    'UserData',                         -2);
            
            detM = uimenu(app.optMarkerContextMenu,'Text','Detectors');
            srcM = uimenu(app.optMarkerContextMenu,'Text','Sources');
            [optIDs,idx] = sort(val);
            
            for k = 1:numel(optIDs)
                menuProps = {'Text',            optIDs{k}, ...
                             'MenuSelectedFcn', @(src,evt)app.cb_assignOptID(src,evt), ...
                             'UserData',        idx(k)};
                
                if ~isempty(regexpi(optIDs{k},'^s\d+$','once')) %  startsWith(optIDs{k},'S','IgnoreCase',true)
                    uimenu(detM,menuProps{:});
                    
                elseif ~isempty(regexpi(optIDs{k},'^d\d+$','once')) % startsWith(optIDs{k},'D',"IgnoreCase",true)
                    uimenu(srcM,menuProps{:});
                    
                else
                    uimenu(app.optMarkerContextMenu,menuProps{:});
                end
            end
        end
        
        
        % =================================================================
        
        
        function plotModlOptPositions(app)
            pos = app.modl_optodePositions;
            optAxis = diff(pos,1,3);
            pos(:,:,2) = pos(:,:,2) + optAxis.*.1;
            ids = app.modl_optodeIDs;
                    
            
            P = [fieldnames(app.props_modlOptode) struct2cell(app.props_modlOptode)].';
            plotProps = [P(:)', ...
                         {'ContextMenu',         app.optMarkerContextMenu, ...
                         'ButtonDownFcn',       @(src,evt)app.cb_assignOptID(src,evt)}];
            textProps =  [fieldnames(app.props_modlOptID) struct2cell(app.props_modlOptID)].';
                        
            for k = size(pos,1):-1:1
                ph(k,1) = plot3(app.UIAxes_modl, ...
                                  pos(k,1,2), ...
                                  pos(k,2,2), ...
                                  pos(k,3,2), ...
                            'UserData',         k,...
                            plotProps{:});
                ph(k,2) = plot3(app.UIAxes_tmpl, ...
                                  pos(k,1,1), ...
                                  pos(k,2,1), ...
                                  pos(k,3,1), ...
                            'UserData',         k,...
                            plotProps{:});
            end
            
            assert(size(pos,1)==numel(ids),'Number of model optode positions and model optode IDs do not match!');
            for k = numel(ids):-1:1
                th(k,1) = text(app.UIAxes_modl, ...
                    pos(k,1,2), pos(k,2,2), pos(k,3,2), ...
                    ids{k},...
                    'UserData',         k,...
                    textProps{:});
                if startsWith(ids{k},'S')
                    set(ph(k,:), app.props_isSrcMarker);
                elseif startsWith(ids{k},'D')
                    set(ph(k,:), app.props_isDetMarker);
                elseif isempty(ids{k})
                    set(ph(k,:), app.props_isUnassigned);
                else
                    set(ph(k,:), app.props_isOtherMarker);
                end
            end

            app.hndl_modlOptodeID = th;
            app.hndl_modlOptodeMarker = ph;
        end
        
        
        % =================================================================
        
        
        function plotTmplOptPositions(app)
            pos = app.tmpl_optodePositions;
            ids = app.tmpl_optodeIDs;
                        
            P = [fieldnames(app.props_tmplOptode) struct2cell(app.props_tmplOptode)].';
            plotProps = [P(:)', ...
                         {'ButtonDownFcn'},       {@(src,evt)app.cb_setLastSelTmplOpt(src,evt)}];
            
            textProps = [fieldnames(app.props_tmplOptID) struct2cell(app.props_tmplOptID)].';
            
            for k = size(pos,1):-1:1
                ph(k,1) = plot3(app.UIAxes_tmpl, ...
                                  pos(k,1), ...
                                  pos(k,2), ...
                                  pos(k,3), ...
                            'UserData',         k,...
                            plotProps{:});
            end
            
            assert(size(pos,1)==numel(ids),'Number of template optode positions and template optode IDs do not match!');
            for k = numel(ids):-1:1
                th(k,1) = text(app.UIAxes_tmpl, ...
                    pos(k,1), pos(k,2), pos(k,3), ...
                    ids{k},...
                    'UserData',         k,...
                    textProps{:});
                if startsWith(ids{k},'S')
                    set(ph(k), app.props_isSrcMarker);
                elseif startsWith(ids{k},'D')
                    set(ph(k), app.props_isDetMarker);
                else
                    set(ph(k), app.props_isOtherMarker);
                end
            end

            app.hndl_tmplOptodeMarker = ph;
            app.hndl_tmplOptodeID = th;
        end
        
        
        % =================================================================
        
        
        function plotSurf(app,typ)
            typ = validatestring(typ,{'modl','tmpl'});
            ah = app.(sprintf('UIAxes_%s',typ));
            srf = app.(sprintf('%s_headSurface',typ));
            
            srfProps = app.(sprintf('props_%sHeadSurf',typ));
            
            ph = patch(ah,'faces',srf.faces, 'vertices',srf.vertices, ...
                       'EdgeColor',             'none', ...
                       'AlphaDataMapping',      'none', ...
                       'FaceLighting',          'gouraud');
            set(ph,srfProps);
            
            if isfield(srf,'color') && ~isempty(srf.color)
                set(ph,'FaceVertexCData', srf.color,...
                    'FaceColor',     'interp');
            else
                set(ph,'FaceColor', [1 1 1] .* .7);
                delete(findobj(ah,'Type','light'));
                lh(1,1) = lightangle(ah,-150,35);
                lh(1,2) = lightangle(ah,150,35);
                lh(1,3) = lightangle(ah,0,35);

            end
            material(ph,'dull');
            
%             set(ah,'XLimMode','manual','YLimMode','manual','ZLimMode','manual');
        end
         
        
        % =================================================================
       
        
        function updateAssignment(app,idx)
            % update lines connecting matched optodes in the template axis
                        
            if      nargin<2,       idx = 1:numel(app.modl_optodeIDs);
            elseif  islogical(idx), idx = find(idx);
            end
            if      isempty(idx),   return; end
            
            selIDs = app.modl_optodeIDs(idx);
            [isAssigned,locb] = ismember(selIDs,app.tmpl_optodeIDs);
            
            
            if ~all(isAssigned | cellfun(@isempty,selIDs))
                msg = [ {'These optodes are not present in the template:'}; ...
                        selIDs(~isAssigned & ~cellfun(@isempty,selIDs)); ...
                        {'Wrong template selected?'}];
                uialert(app.ReviewOptodeIdentitiesUIFigure,...
                    msg, 'Optode ID missmatch');
                warning('%s\n',msg{:});
            end
            
            % delete respective connections
            h = findobj(app.UIAxes_tmpl,'Type','line','Tag',app.props_assignLine.Tag);
            if ~isempty(h)
                k = [h.UserData];
                hIdx = ismember(k,idx);
                delete(h(hIdx));
            end

            % update optode ID string
            for k = 1:numel(idx)
                h = findobj([app.UIAxes_tmpl, app.UIAxes_modl], ...
                    'Type',             'line', ...
                    'Tag',              app.props_modlOptode.Tag, ...
                    'UserData',         idx(k));

                if ~isAssigned,     set(h,app.props_isUnassigned)
                elseif startsWith(selIDs{k},'S')
                                    set(h,app.props_isSrcMarker);
                elseif startsWith(selIDs{k},'D')
                                    set(h,app.props_isDetMarker);
                else,               set(h,app.props_isOtherMarker);
                end

                if app.modl_optodeIsManAssigned(idx(k))
                    set(h,app.props_manAssigned);
                end

                h = findobj(app.UIAxes_modl, ...
                    'Type',             'text', ...
                    'Tag',              app.props_modlOptID.Tag, ...
                    'UserData',         idx(k));
                set(h,'String',selIDs{k});
            end
            
            
            for k = find(isAssigned(:)).'
                % redraw connection
                x = [app.modl_optodePositions(idx(k),1,1); app.tmpl_optodePositions(locb(k),1)];
                y = [app.modl_optodePositions(idx(k),2,1); app.tmpl_optodePositions(locb(k),2)];
                z = [app.modl_optodePositions(idx(k),3,1); app.tmpl_optodePositions(locb(k),3)];
                P = [fieldnames(app.props_assignLine) struct2cell(app.props_assignLine)].';
                plot3(app.UIAxes_tmpl, x,y,z, ...
                    P{:}, ...
                    'UserData',         idx(k));
            end
            
        end
        
        
         % =================================================================
       
       
        function assignOptID(app,modlK,tmplK)
            app.modl_optodeIDs(modlK) = app.tmpl_optodeIDs(tmplK);
        end
    end % end methods public
    
    
    %% #################### METHODS private ##########################
    
    
    methods (Access = private)
        
        function cb_assignOptID(app,src,evnt)
            
            
            if isprop(evnt,'Button')
            % clicked an optode . . . . . . . . . . . . . . . . . . . . . .
                k_modlOpt = src.UserData;
                if evnt.Button == 1
                % left click - assign previous selected template optode
                    if ~isempty(app.lastSelectedTmplOpt)
                        app.assignOptID(k_modlOpt,app.lastSelectedTmplOpt.UserData);
                    end
                elseif evnt.Button == 3
                % right click - save clicked optode in contextMenu
                    app.optMarkerContextMenu.UserData = k_modlOpt;
                end
                
                
            elseif isa(src,'matlab.ui.container.Menu')
            % clicked context menu . . . . . . . . . . . . . . . . . . . . 
                k_modlOpt = app.optMarkerContextMenu.UserData;
                ud = src.UserData;
                if isnumeric(ud) && isscalar(ud)
                    if ud>0
                        k_tmplOpt = ud;
                        app.assignOptID(k_modlOpt,k_tmplOpt);
                    elseif ud == -1 % unassign ID
                        app.modl_optodeIDs{k_modlOpt} = '';
                    elseif us == -2 % delete optode
                        
                    end                    
                end
            end
        end
        
        % ============================================================
        
        function cb_setLastSelTmplOpt(app,src,evnt)
            app.lastSelectedTmplOpt = src;
        end
        
        % ============================================================        
        
        function plotLegends(app)

            % template surface
            k=1;
            h(k) = patch(app.TemplateSurfaceLegendAx,'Tag',app.props_tmplHeadSurf.Tag,'Visible','off');
            
            % model surface
            k=k+1;
            h(k) = patch(app.ModelSurfaceLegendAx,'Tag',app.props_modlHeadSurf.Tag,'Visible','off');
            
            % template optode
            k=k+1;
            h(k) = plot(app.TemplateOptodesLegendAx,1,1,app.props_tmplOptode);
            set(h(k),app.props_isSrcMarker);
            k=k+1;
            h(k) = text(app.TemplateOptodesLegendAx,1,1,'S01',app.props_tmplOptID);
            
            % assigned optodes
            k=k+1;
            h(k) = plot(app.AssignedOptodeMarkersLegendAx,1,1,app.props_modlOptode);
            set(h(k),app.props_isSrcMarker);
            k=k+1;
            h(k) = text(app.AssignedOptodeMarkersLegendAx,1,1,'S01',app.props_modlOptID);
            
            % manually assigned optodes
            k=k+1;
            h(k) = plot(app.ManuallyAssignedOptodesLegendAx,1,1,app.props_modlOptode);
            set(h(k),app.props_isSrcMarker);
            set(h(k),app.props_manAssigned);
            k=k+1;
            h(k) = text(app.ManuallyAssignedOptodesLegendAx,1,1,'S01',app.props_modlOptID);
            
            % unassigned optodes
            k=k+1;
            h(k) = plot(app.UnassignedOptodesLegendAx,1,1,app.props_modlOptode);
            set(h(k),app.props_isSrcMarker);
            set(h(k),app.props_isUnassigned);
            k=k+1;
            h(k) = text(app.UnassignedOptodesLegendAx,1,1,'S01',app.props_modlOptID);
            
            k=k+1;
            h(k) = plot(app.OptodeAssignmentsLegendAx,[0 2],[1 1],app.props_assignLine);

            set(h,'PickableParts','none','HitTest','off');
        end
        
        % ============================================================        
        
        function propNam = getPropName(app,prop)
            P = properties(app);
            k = cellfun(@(x)all(app.(x)==prop,'all'),P);
            propNam = P{k};            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            Link = linkprop([app.UIAxes_modl app.UIAxes_tmpl], ...
                {'CameraUpVector', 'CameraPosition', ...
                 'CameraTarget', 'XLim', 'YLim', 'ZLim'});
            setappdata(app.ReviewOptodeIdentitiesUIFigure, 'AxesLink', Link);
            axis([app.UIAxes_modl app.UIAxes_tmpl],'vis3d','equal');
            
            app.optMarkerContextMenu = uicontextmenu(app.ReviewOptodeIdentitiesUIFigure);
            
%             app.optMarkerContextMenu.Parent = app.ReviewOptodeIdentitiesUIFigure;
            
            getRepNoHref = @(me)regexprep(getReport(me,'basic','hyperlinks','off'),'</?a[^>]*>','');
            
            nvain = numel(varargin);
%             flag = 0;
            for k = 1:nvain
%                 if flag > 0, flag = flag-1; continue; end
%                 if isprop(app,varargin{k})
%                     assert(k<nvain,'Missing value for input property ''%s''!',varargin{k});
%                     app.(varargin{k}) = varargin{k+1};
%                     flag = 1;
%                     
%                 else, error('Unrecognized input at input %d',k);
%                 end
                if isempty(varargin{k}), continue; end
                if k<=numel(app.inputArgsPropertyNames)
                    pn = app.inputArgsPropertyNames{k};
                    try
                        app.(pn) = varargin{k};                        
                    catch ME
                        msg = {sprintf('Failed to assign input argument %d to property ''%s'' because:',k,pn), ...
                               getRepNoHref(ME)};
                        uialert(app.ReviewOptodeIdentitiesUIFigure,...
                            msg, 'Failed to set app property value');
                        rethrow(ME);
                    end
                end
            end
            
            
            try
                if isempty(app.modl_optodeIDs)
                    app.modl_optodeIDs = repmat({''},size(app.modl_optodePositions,1),1);
                end
                app.plotModlOptPositions();
                app.plotTmplOptPositions();
                app.updateOptContextMenu();
            catch ME
                msg = {'There was an error while setting up the graphics objects:', ...
                       getRepNoHref(ME)};
                uialert(app.ReviewOptodeIdentitiesUIFigure,...
                    msg, 'Error setting up graphics');
                rethrow(ME);
            end
            
            app.plotLegends();
        end

        % Callback function: AssignedOptodeMarkersCheckBox, 
        % ...and 13 other components
        function cb_triggerGfxVisibility(app, event)
            h = event.Source;
            hName = app.getPropName(h);
            if strcmpi(h.Type,'axes')
                ah = h;
                h = app.(regexprep(hName,'LegendAx$','CheckBox','once'));
                h.Value = ~h.Value;
            elseif strcmpi(h.Type,'uicheckbox')
                ah = app.(regexprep(hName,'CheckBox$','LegendAx','once'));
            else
                warning('Unexpected callback source ''%s'' (%s)!',hName,h.Type);
                return;
            end
            
            tags = get([ah.Children],'Tag');
            
            allgfx = [app.UIAxes_tmpl.Children; app.UIAxes_modl.Children];
            allgfx = allgfx(isprop(allgfx,'Tag'));
            gfxTags = get(allgfx,'Tag');
            
            k = cellfun(@(x)any(strcmp(tags,x)),gfxTags);
            gfx = allgfx(k);
            
            k = true(size(gfx));
            udCell = get(gfx,'UserData');
            if ~iscell(udCell), udCell = {udCell}; end
            udIsK = cellfun(@(x)isscalar(x) && isnumeric(x),udCell);
            ud = nan(size(udCell));
            ud(udIsK) = [udCell{udIsK}];
            
            switch h
                case app.TemplateSurfaceCheckBox
                case app.ModelSurfaceCheckBox
                case app.TemplateOptodesCheckBox
                case app.AssignedOptodeMarkersCheckBox
                    k = ~ismember(ud, find(app.modl_optodeIsManAssigned));
                case app.ManuallyAssignedOptodesCheckBox
                    k = ismember(ud, find(app.modl_optodeIsManAssigned));
                case app.UnassignedOptodesCheckBox
                    kEmpty = cellfun(@isempty,app.modl_optodeIDs);
                    k = ismember(ud, find(kEmpty));
                case app.OptodeAssignmentsCheckBox
                otherwise, error('BUG!');
            end
            
            set(gfx(k),'Visible',h.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReviewOptodeIdentitiesUIFigure = uifigure('Visible', 'off');
            app.ReviewOptodeIdentitiesUIFigure.Name = 'Review Optode Identities';
            app.UIAxes_tmpl = uiaxes(app.ReviewOptodeIdentitiesUIFigure);
            app.UIAxes_tmpl.XTick = [];
            app.UIAxes_tmpl.YTick = [];
            app.UIAxes_tmpl.ZTick = [];
            app.UIAxes_modl = uiaxes(app.ReviewOptodeIdentitiesUIFigure);
            app.UIAxes_modl.XTick = [];
            app.UIAxes_modl.YTick = [];
            app.UIAxes_modl.ZTick = [];
            app.SaveButton = uibutton(app.ReviewOptodeIdentitiesUIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.RegisterButton = uibutton(app.ReviewOptodeIdentitiesUIFigure, 'push');
            app.RegisterButton.Enable = 'off';
            app.RegisterButton.Text = 'Register';
            app.RealignButton = uibutton(app.ReviewOptodeIdentitiesUIFigure, 'push');
            app.RealignButton.Enable = 'off';
            app.RealignButton.Tooltip = {'Manually assign at least 3 Optode IDs using the context menu in the left panel to realign the optode positions to the template. Then run registration again.'};
            app.RealignButton.Text = 'Realign';
            app.centerbTemplatebbrRealignedtomodeloptodepositionscenterLabel = uilabel(app.ReviewOptodeIdentitiesUIFigure);
            app.centerbTemplatebbrRealignedtomodeloptodepositionscenterLabel.Interpreter = 'html';
            app.centerbTemplatebbrRealignedtomodeloptodepositionscenterLabel.Text = '<center><b>Template</b></br>(Realigned to model optode positions)</center>';
            app.centerbModelbbrLabel = uilabel(app.ReviewOptodeIdentitiesUIFigure);
            app.centerbModelbbrLabel.Interpreter = 'html';
            app.centerbModelbbrLabel.Text = '<center><b>Model</b></br> ';
            app.DisplayOptionsPanel = uipanel(app.ReviewOptodeIdentitiesUIFigure);
            app.DisplayOptionsPanel.Title = 'Display Options / Legend';
            app.OptodeAssignmentsLegendAx = uiaxes(app.DisplayOptionsPanel);
            app.OptodeAssignmentsLegendAx.XLim = [0 2];
            app.OptodeAssignmentsLegendAx.YLim = [0 2];
            app.OptodeAssignmentsLegendAx.XTick = [];
            app.OptodeAssignmentsLegendAx.YTick = [];
            app.OptodeAssignmentsLegendAx.ZTick = [];
            app.OptodeAssignmentsLegendAx.ButtonDownFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.ManuallyAssignedOptodesLegendAx = uiaxes(app.DisplayOptionsPanel);
            app.ManuallyAssignedOptodesLegendAx.XLim = [0 2];
            app.ManuallyAssignedOptodesLegendAx.YLim = [0 2];
            app.ManuallyAssignedOptodesLegendAx.XTick = [];
            app.ManuallyAssignedOptodesLegendAx.YTick = [];
            app.ManuallyAssignedOptodesLegendAx.ZTick = [];
            app.ManuallyAssignedOptodesLegendAx.ButtonDownFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.AssignedOptodeMarkersLegendAx = uiaxes(app.DisplayOptionsPanel);
            app.AssignedOptodeMarkersLegendAx.XLim = [0 2];
            app.AssignedOptodeMarkersLegendAx.YLim = [0 2];
            app.AssignedOptodeMarkersLegendAx.XTick = [];
            app.AssignedOptodeMarkersLegendAx.YTick = [];
            app.AssignedOptodeMarkersLegendAx.ZTick = [];
            app.AssignedOptodeMarkersLegendAx.ButtonDownFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.TemplateOptodesLegendAx = uiaxes(app.DisplayOptionsPanel);
            app.TemplateOptodesLegendAx.XLim = [0 2];
            app.TemplateOptodesLegendAx.YLim = [0 2];
            app.TemplateOptodesLegendAx.XTick = [];
            app.TemplateOptodesLegendAx.YTick = [];
            app.TemplateOptodesLegendAx.ZTick = [];
            app.TemplateOptodesLegendAx.ButtonDownFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.ModelSurfaceLegendAx = uiaxes(app.DisplayOptionsPanel);
            app.ModelSurfaceLegendAx.XLim = [0 2];
            app.ModelSurfaceLegendAx.YLim = [0 2];
            app.ModelSurfaceLegendAx.XTick = [];
            app.ModelSurfaceLegendAx.YTick = [];
            app.ModelSurfaceLegendAx.ZTick = [];
            app.ModelSurfaceLegendAx.ButtonDownFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.UnassignedOptodesLegendAx = uiaxes(app.DisplayOptionsPanel);
            app.UnassignedOptodesLegendAx.XLim = [0 2];
            app.UnassignedOptodesLegendAx.YLim = [0 2];
            app.UnassignedOptodesLegendAx.XTick = [];
            app.UnassignedOptodesLegendAx.YTick = [];
            app.UnassignedOptodesLegendAx.ZTick = [];
            app.UnassignedOptodesLegendAx.ButtonDownFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.TemplateSurfaceLegendAx = uiaxes(app.DisplayOptionsPanel);
            app.TemplateSurfaceLegendAx.XLim = [0 2];
            app.TemplateSurfaceLegendAx.YLim = [0 2];
            app.TemplateSurfaceLegendAx.XTick = [];
            app.TemplateSurfaceLegendAx.YTick = [];
            app.TemplateSurfaceLegendAx.ZTick = [];
            app.TemplateSurfaceLegendAx.ButtonDownFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.AssignedOptodeMarkersCheckBox = uicheckbox(app.DisplayOptionsPanel);
            app.AssignedOptodeMarkersCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.AssignedOptodeMarkersCheckBox.Text = 'Assigned Optode Markers';
            app.AssignedOptodeMarkersCheckBox.Value = true;
            app.OptodeAssignmentsCheckBox = uicheckbox(app.DisplayOptionsPanel);
            app.OptodeAssignmentsCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.OptodeAssignmentsCheckBox.Text = 'Optode Assignments';
            app.OptodeAssignmentsCheckBox.Value = true;
            app.ManuallyAssignedOptodesCheckBox = uicheckbox(app.DisplayOptionsPanel);
            app.ManuallyAssignedOptodesCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.ManuallyAssignedOptodesCheckBox.Text = 'Manually Assigned Optodes';
            app.ManuallyAssignedOptodesCheckBox.Value = true;
            app.TemplateOptodesCheckBox = uicheckbox(app.DisplayOptionsPanel);
            app.TemplateOptodesCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.TemplateOptodesCheckBox.Text = 'Template Optodes';
            app.TemplateOptodesCheckBox.Value = true;
            app.ModelSurfaceCheckBox = uicheckbox(app.DisplayOptionsPanel);
            app.ModelSurfaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.ModelSurfaceCheckBox.Text = 'Model Surface';
            app.ModelSurfaceCheckBox.Value = true;
            app.TemplateSurfaceCheckBox = uicheckbox(app.DisplayOptionsPanel);
            app.TemplateSurfaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.TemplateSurfaceCheckBox.Text = 'Template Surface';
            app.TemplateSurfaceCheckBox.Value = true;
            app.UnassignedOptodesCheckBox = uicheckbox(app.DisplayOptionsPanel);
            app.UnassignedOptodesCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.UnassignedOptodesCheckBox.Text = 'Unassigned Optodes';
            app.UnassignedOptodesCheckBox.Value = true;
            % Show the figure after all components are created
            app.ReviewOptodeIdentitiesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReviewOptodeIDs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReviewOptodeIdentitiesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReviewOptodeIdentitiesUIFigure)
        end
    end
end